classdef displayApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        totalPriceLabel  matlab.ui.control.Label
        PowerProfile     matlab.ui.control.UIAxes
        Price            matlab.ui.control.UIAxes
        powerUsage       matlab.ui.control.UIAxes
        BatterySOC       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;
            
            [~,~,~,~,plotData] = optimisedCost();
            time = plotData.time;
            plot(app.powerUsage,time,plotData.balancedPower,'g',time,plotData.unbalancedPower,'r');
            title(app.powerUsage,'Net Grid Draw');
            xlabel(app.powerUsage,'Time');
            ylabel(app.powerUsage,'Net Power (kW)');
            legend(app.powerUsage,'Shifted','Unshifted');

            plot(app.PowerProfile,time,-plotData.batteryProfile,'k',time,zeros(size(time)),'r.');
            hold(app.PowerProfile,"on");
            plot(app.PowerProfile,time,plotData.PV,'Color',[0.9290 0.6940 0.1250]);
            hold(app.PowerProfile, "off");
            legend(app.PowerProfile,'Battery Draw','','Solar Generation');
            title(app.PowerProfile,'Energy Flow per Time Step');
            xlabel(app.PowerProfile,'Time');
            ylabel(app.PowerProfile,'Power (kW)');
            
            plot(app.Price,time,plotData.balancedPrice,'g',time,plotData.unbalancedPrice,'r');
            title(app.Price,'Cost Contribution');
            xlabel(app.Price,'Time');
            ylabel(app.Price,'Grid Cost per Time Step (pence)');
            legend(app.Price,'Shifted','Unshifted');
            
            plot(app.BatterySOC,time,plotData.SOC,'b');
            title(app.BatterySOC,'State of Charge');
            xlabel(app.BatterySOC, 'Time');
            ylabel(app.BatterySOC,'SOC (kWh)');

            DSF = plotData.DailyShiftedPrice;
            DUF = plotData.DailyUnshiftedPrice;
            app.totalPriceLabel.Text = append('Total Price: £',string(round(DSF)/100),', Saved £',string(round(DUF-DSF)/100),' (',string(round((DSF-DUF)*100/DUF,1)),'%)');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BatterySOC = uiaxes(app.GridLayout);
            title(app.BatterySOC, 'Title')
            xlabel(app.BatterySOC, 'X')
            ylabel(app.BatterySOC, 'Y')
            zlabel(app.BatterySOC, 'Z')
            app.powerUsage = uiaxes(app.GridLayout);
            title(app.powerUsage, 'Title')
            xlabel(app.powerUsage, 'X')
            ylabel(app.powerUsage, 'Y')
            zlabel(app.powerUsage, 'Z')
            app.Price = uiaxes(app.GridLayout);
            title(app.Price, 'Title')
            xlabel(app.Price, 'X')
            ylabel(app.Price, 'Y')
            zlabel(app.Price, 'Z')
            app.PowerProfile = uiaxes(app.GridLayout);
            title(app.PowerProfile, 'Title')
            xlabel(app.PowerProfile, 'X')
            ylabel(app.PowerProfile, 'Y')
            zlabel(app.PowerProfile, 'Z')
            app.totalPriceLabel = uilabel(app.GridLayout);
            app.totalPriceLabel.Text = 'totalPrice';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = displayApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end